classdef Pedal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        PlayButton             matlab.ui.control.Button
        Label                  matlab.ui.control.Label
        DecayKnob              matlab.ui.control.Knob
        MixKnob                matlab.ui.control.Knob
        Switch                 matlab.ui.control.ToggleSwitch
        Reverbmaster9000Label  matlab.ui.control.Label
        MixKnobLabel           matlab.ui.control.Label
        WetnessKnobLabel       matlab.ui.control.Label
        DecayKnobLabel         matlab.ui.control.Label
        WetnessKnob            matlab.ui.control.Knob
        Label_2                matlab.ui.control.Label
        DistGranLabel          matlab.ui.control.Label
        DISTKnobLabel          matlab.ui.control.Label
        DISTKnob               matlab.ui.control.Knob
        Label_3                matlab.ui.control.Label
        MixKnob_2Label         matlab.ui.control.Label
        MixKnob_2              matlab.ui.control.Knob
        DelayKnobLabel         matlab.ui.control.Label
        DelayKnob              matlab.ui.control.Knob
        FeedbackKnobLabel      matlab.ui.control.Label
        FeedbackKnob           matlab.ui.control.Knob
    end

    
    properties (Access = public)
    WetDryMix = 0
    DecayFactor = 1
    Diffusion = 0.5
    fileReader;
    deviceWriter;
    reverb;
    
    kDist=0.9;
    
    WetDryMixDelay = 0;   % [0 ,   1] Wet to dry signal ratio
    Delay = 0.3;          % [0 ,   1] Base delay in seconds
    FeedbackLevel = 0.35; % [0 , 0.5] Feedback gain 
    echo;
    
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        app.fileReader = dsp.AudioFileReader('gitarr.wav','SamplesPerFrame',1024);
        app.deviceWriter = audioDeviceWriter('SampleRate', app.fileReader.SampleRate);
        app.reverb = reverberator('WetDryMix', app.WetDryMix,'DecayFactor', app.DecayFactor, 'Diffusion', app.Diffusion);
        %app.echo = audiopluginexample.Echo(); THIS FUNCTION IS EVIL
        app.echo = audioexample.Echo('Delay',app.Delay,'FeedbackLevel',app.FeedbackLevel,'WetDryMix',app.WetDryMixDelay);

        
        end

        % Value changing function: MixKnob
        function MixKnobValueChanging(app, event)
            mixValue = event.Value;
            app.WetDryMix = mixValue;
            app.reverb = reverberator('WetDryMix', app.WetDryMix,'DecayFactor', app.DecayFactor, 'Diffusion', app.Diffusion);
            
        end

        % Value changing function: WetnessKnob
        function WetnessKnobValueChanging(app, event)
            wetValue = event.Value;
            app.WetDryMix = wetValue;
            app.reverb = reverberator('WetDryMix', app.WetDryMix,'DecayFactor', app.DecayFactor, 'Diffusion', app.Diffusion);
        end

        % Value changing function: DecayKnob
        function DecayKnobValueChanging(app, event)
            decayValue = event.Value;
            app.DecayFactor = decayValue;
            app.reverb = reverberator('WetDryMix', app.WetDryMix,'DecayFactor', app.DecayFactor, 'Diffusion', app.Diffusion);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
        while ~isDone(app.fileReader)
            audio = app.fileReader();
            drawnow 
            audioWithDist = (1+app.kDist)*(audio)./(1+app.kDist*abs(audio));
            audioWithDelay = app.echo(audioWithDist);
            audioWithReverb = app.reverb(audioWithDelay);
            app.deviceWriter(audioWithReverb);
        end
        release(app.fileReader);
        end

        % Value changing function: DISTKnob
        function DISTKnobValueChanging(app, event)
            distValue = event.Value;
            app.kDist = 2*distValue/(1-distValue);
        end

        % Value changing function: DelayKnob
        function DelayKnobValueChanging(app, event)
            changingValue = event.Value;
            app.Delay = changingValue;
            app.echo = audioexample.Echo('Delay',app.Delay,'FeedbackLevel',app.FeedbackLevel,'WetDryMix',app.WetDryMixDelay);
        end

        % Value changing function: MixKnob_2
        function MixKnob_2ValueChanging(app, event)
            changingValue = event.Value;
            app.WetDryMixDelay = changingValue;
            app.echo = audioexample.Echo('Delay',app.Delay,'FeedbackLevel',app.FeedbackLevel,'WetDryMix',app.WetDryMixDelay);
        end

        % Value changing function: FeedbackKnob
        function FeedbackKnobValueChanging(app, event)
            changingValue = event.Value;
            app.FeedbackLevel = changingValue;
            app.echo = audioexample.Echo('Delay',app.Delay,'FeedbackLevel',app.FeedbackLevel,'WetDryMix',app.WetDryMixDelay);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = {'Play'; ''};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.DecayKnob = uiknob(app.UIFigure, 'continuous');
            app.DecayKnob.Limits = [0 1];
            app.DecayKnob.ValueChangingFcn = createCallbackFcn(app, @DecayKnobValueChanging, true);
            app.DecayKnob.Value = 1;
            app.MixKnob = uiknob(app.UIFigure, 'continuous');
            app.MixKnob.Limits = [0 1];
            app.MixKnob.ValueChangingFcn = createCallbackFcn(app, @MixKnobValueChanging, true);
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Reverbmaster9000Label = uilabel(app.UIFigure);
            app.Reverbmaster9000Label.Text = {'Reverbmaster 9000'; ''; ''; ''; ''};
            app.MixKnobLabel = uilabel(app.UIFigure);
            app.MixKnobLabel.Text = 'Mix';
            app.WetnessKnobLabel = uilabel(app.UIFigure);
            app.WetnessKnobLabel.Text = 'Wetness';
            app.DecayKnobLabel = uilabel(app.UIFigure);
            app.DecayKnobLabel.Text = 'Decay';
            app.WetnessKnob = uiknob(app.UIFigure, 'continuous');
            app.WetnessKnob.ValueChangingFcn = createCallbackFcn(app, @WetnessKnobValueChanging, true);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.DistGranLabel = uilabel(app.UIFigure);
            app.DistGranLabel.Text = {'Dist-Göran'; ''; ''; ''; ''; ''};
            app.DISTKnobLabel = uilabel(app.UIFigure);
            app.DISTKnobLabel.Text = {'DIST'; ''};
            app.DISTKnob = uiknob(app.UIFigure, 'continuous');
            app.DISTKnob.Limits = [0 1];
            app.DISTKnob.MajorTicks = [0 1 5];
            app.DISTKnob.ValueChangingFcn = createCallbackFcn(app, @DISTKnobValueChanging, true);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.MixKnob_2Label = uilabel(app.UIFigure);
            app.MixKnob_2Label.Text = 'Mix';
            app.MixKnob_2 = uiknob(app.UIFigure, 'continuous');
            app.MixKnob_2.Limits = [0 1];
            app.MixKnob_2.ValueChangingFcn = createCallbackFcn(app, @MixKnob_2ValueChanging, true);
            app.MixKnob_2.Value = 1;
            app.DelayKnobLabel = uilabel(app.UIFigure);
            app.DelayKnobLabel.Text = 'Delay';
            app.DelayKnob = uiknob(app.UIFigure, 'continuous');
            app.DelayKnob.Limits = [0 1];
            app.DelayKnob.ValueChangingFcn = createCallbackFcn(app, @DelayKnobValueChanging, true);
            app.FeedbackKnobLabel = uilabel(app.UIFigure);
            app.FeedbackKnobLabel.Text = 'Feedback';
            app.FeedbackKnob = uiknob(app.UIFigure, 'continuous');
            app.FeedbackKnob.Limits = [0 0.5];
            app.FeedbackKnob.ValueChangingFcn = createCallbackFcn(app, @FeedbackKnobValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Pedal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end